classdef Face_Recognition_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image3                     matlab.ui.control.Image
        ImageInfoColumnPanel       matlab.ui.container.Panel
        ImgInfo                    matlab.ui.control.Label
        RecongnitionResultPanel    matlab.ui.container.Panel
        RecongResultLabel          matlab.ui.control.Label
        ImageDisplayAreaPanel      matlab.ui.container.Panel
        TabGroup                   matlab.ui.container.TabGroup
        Tab                        matlab.ui.container.Tab
        ImgParamsPanel             matlab.ui.container.Panel
        ImgParams                  matlab.ui.control.Label
        ImgProcessingToolBoxPanel  matlab.ui.container.Panel
        PlotGrayHistButton         matlab.ui.control.Button
        ImgRotateButton            matlab.ui.control.Button
        ImgZoomButton              matlab.ui.control.Button
        Label_7                    matlab.ui.control.Label
        ImgReverseModeButton       matlab.ui.container.ButtonGroup
        level                      matlab.ui.control.RadioButton
        vertical                   matlab.ui.control.RadioButton
        ImgReverseButton           matlab.ui.control.Button
        ImgRotateParams            matlab.ui.control.EditField
        Label_6                    matlab.ui.control.Label
        ImgShearParams             matlab.ui.control.EditField
        Label_5                    matlab.ui.control.Label
        ImgShearButton             matlab.ui.control.Button
        ImgZoomParams              matlab.ui.control.EditField
        Label_4                    matlab.ui.control.Label
        ImgShiftParams             matlab.ui.control.EditField
        Label_3                    matlab.ui.control.Label
        ImgShiftButton             matlab.ui.control.Button
        ImgTransFuncShowButton     matlab.ui.control.Button
        GetImgParamButton          matlab.ui.control.Button
        MainFunctionBlockPanel     matlab.ui.container.Panel
        ClearImgButton             matlab.ui.control.Button
        InputImg                   matlab.ui.control.Button
        IsChooseFigureDropDown     matlab.ui.control.DropDown
        Label_2                    matlab.ui.control.Label
        ImgDispModDropDown         matlab.ui.control.DropDown
        Label                      matlab.ui.control.Label
        ImgEqualizeButton          matlab.ui.control.Button
        IngGrayButton              matlab.ui.control.Button
        Tab_2                      matlab.ui.container.Tab
        Panel_9                    matlab.ui.container.Panel
        NameNumberText             matlab.ui.control.TextArea
        Label_10                   matlab.ui.control.Label
        NameText                   matlab.ui.control.TextArea
        Label_9                    matlab.ui.control.Label
        videoinputImgButton        matlab.ui.control.Button
        ReinputImgButton           matlab.ui.control.Button
        ClearImgButton_2           matlab.ui.control.Button
        ModelIintialButton_2       matlab.ui.control.Button
        FaceRecongButton           matlab.ui.control.Button
        ModelIintialButton         matlab.ui.control.Button
        ReadTrainingDataButton     matlab.ui.control.Button
        Panel_8                    matlab.ui.container.Panel
        SaveModelButton            matlab.ui.control.Button
        TrainingSVMButton          matlab.ui.control.Button
        DispPCAFaceButton          matlab.ui.control.Button
        PCALabel_3                 matlab.ui.control.Label
        PCACharactersDimenText     matlab.ui.control.EditField
        PCALabel_2                 matlab.ui.control.Label
        EigsProjectLabel           matlab.ui.control.Label
        EigsProjectButton          matlab.ui.control.Button
        EigsNormalizeLabel         matlab.ui.control.Label
        EigsNormalizeButton        matlab.ui.control.Button
        GetEigenvectorLabel        matlab.ui.control.Label
        GetEigenvectorButton       matlab.ui.control.Button
        GetCovMat                  matlab.ui.control.Label
        GetCovMatButton            matlab.ui.control.Button
        RemoveAverageLabel         matlab.ui.control.Label
        RemoveAverageButton        matlab.ui.control.Button
        SVMTrainParamGText         matlab.ui.control.TextArea
        gLabel                     matlab.ui.control.Label
        SVMTrainParamCText         matlab.ui.control.TextArea
        cLabel                     matlab.ui.control.Label
        CharacDataNormalizeButton  matlab.ui.control.Button
        TrainSetRatioText          matlab.ui.control.TextArea
        Label_8                    matlab.ui.control.Label
        PCALabel                   matlab.ui.control.Label
        Image                      matlab.ui.control.Image
    end

    
    properties(Access = private)
        
        Property % Description
        img = {};                   %创建大小不定的元胞数组，用于存储图像
        imgtemp = {};               %图像暂存,用于保存原图像
        file = {};
        background = imread('ui背景3.jpg');
        isinputimgflag = 0;         %用户是否输入图像flag
        vidinput = 0;
        dic_cell=importdata('dic_cell.mat')
        video=0;%摄像头
        namenumber;
        %PCA特征及模型训练变量区域
        PCAStepFlag = 0;            %PCA步骤flag，用以标记PCA进行到哪一步，以便后续操作处理
        V;                          %PCA特征
        k;                          %PCA维度
        svmmodel;                   %SVM模型
        svmmodeltemp;               %SVM模型缓存变量
        svmmodelreadyflag;          %用户自己训练svm模型完成标志位
        modelreadyflag = 0;
        facedatareadyflag = 0;
        mA;                         %训练集均值
        meanVec;                    %均值向量
        CovMat;                     %协方差矩阵
        pcaA;                       %PCA特征
        lowvec;                     %lowvec和upvec是归一化参数 
        upvec;
        scaledtrainface;            %规范化后的训练集特征数据
        testlabel;                  %测试集标签
        orlimgrow = 128;             %orl数据集的图像大小
        orlimgcol = 128;
        trainset = 0;               %训练集数据
        trainsetlabel = 0;          %训练集标签 
        testset = 0;                %测试集数据
        testsetlabel = 0;           %测试集标签
    end

        methods (Access = private)
        function infostring = CreateImgInfoString(~, filename, imginfo)
        % Brief:    返回图像信息字符串
        % Input:    imginfo     图像信息        
        % Output    infostring  图像信息字符串          
        % Usage Sample:         DispImgInfo(app.file, imginfo)
        switch imginfo.ColorType
             case   'truecolor'
                 ColorType = '真彩色图';
             case   'grayscale'
                 ColorType = '灰度图';
             case   'indexed'
                 ColorType = '索引图';
             otherwise
                 ColorType = '图像类型未知';
        end
        infostring = {strcat('图像名称:',32,32,filename),strcat('图像格式:',32,32,imginfo.Format),strcat('图像宽度:',32,32,num2str(imginfo.Width))...
                      strcat('图像高度:',32,32,num2str(imginfo.Height)),strcat('图像大小:',32,32,num2str(imginfo.FileSize),'字节')...
                      strcat('像素位数:',32,32,num2str(imginfo.BitDepth)),strcat('图像类型:',32,32,ColorType)};
        end


        function [MaxVal, MinVal, Avr, Var] = GetImgParams(~,ImgInput)
        % Input:    ImgInput    输入图像        
        % Output:   MaxVal      图像当中的最大值
        %           MinIndex    图像当中最小值所在位置
        %           Avr         图像所有数据的平均值
        %           Var         图像所有数据方差
        % Usage Sample:         ......
        
        [h,w,dims] = size(ImgInput);
        %一定要对数据做处理,转换成double类型数据,否则计算结果误差较大,这是由于uint8类型数据的舍入误差引起的
        temp = double(ImgInput);
        
        MaxVal = max(max(max(temp)));  
        MinVal = min(min(min(temp)));   
        result = sum(sum(sum(temp)));
        Avr = result / (h*w*dims);
        Var = sum(sum(sum((temp-Avr).^2))) / (h*w*dims);
        
        end


        function hist = plotgrayhist(~,ImgInput)
        % Input:    ax          绘图区域
        %           ImgInput    输入图像        
        % Output:   hist        输入图像的直方图数据,可用bar画图
        % Usage Sample:         ......
        
        %求取图像大小
        [h,w,dims] = size(ImgInput);
        %将灰度矩阵+1避免后续hist(0)类型的索引出现
        temp = ImgInput + 1;
        %判断是否为RGB图像
        if  dims == 3
            model = 1;
        else
            model = 2;
        end
        
        %初始化灰度直方图数组
        hist = zeros(256,1);
        switch  model
            case 1
                for i=1:3
                    for j=1:w
                        for m=1:h
                            %统计灰度直方图
                            hist(temp(m,j,i)) = hist(temp(m,j,i))+1;
                        end
                    end
                end
        
            case 2
                for j=1:w
                    for m=1:h
                        %统计灰度直方图
                        hist(temp(m,j)) = hist(temp(m,j))+1;
                    end
                end
        end
        x = 0:1:255;
        bar(x,hist);
        end


        function ImgOut = ImgShift(~,ImgInput,ShiftScale)
        % Input:    ImgInput    输入图像
        %           ShiftScale  水平垂直方向上的尺度,一个包含两个元素的向量
        %                       第一个元素决定左右方向上的平移,负号表示向左,正号表示向右
        %                       第二个元素决定上下方向上的平移,负号表示向下,正号表示向上
        % Output:   ImgOut      经过平移后的图像数据
        % Usage Sample:         ImgNew = ImgShift(MyImg, [26,5])
        %                       表示将MyImg图像向右平移26个单位,向上平移5个单位,得到ImgNew图像
        
        %获取输入图像的尺寸
        [h,w,dims] = size(ImgInput);
        ImgOut = zeros(h, w, dims,'uint8');
        
        if  ShiftScale(1) >= 0 && ShiftScale(2) >= 0               %向右上平移
            for i=1:h-ShiftScale(2)
                for j=1:w-ShiftScale(1)
                    ImgOut(i,j+ShiftScale(1),:) = ImgInput(i+ShiftScale(2),j,:);
                end
            end           
        
        elseif  ShiftScale(1) >= 0 && ShiftScale(2) <= 0            %向右下平移
            for i=1:h-abs(ShiftScale(2))
                for j=1:w-(ShiftScale(1))
                    ImgOut(i+abs(ShiftScale(2)),j+ShiftScale(1),:) = ImgInput(i,j,:);
                end
            end
        
        elseif  ShiftScale(1) <= 0 && ShiftScale(2) >= 0            %向左上平移
            for i=1:h-ShiftScale(2)
                for j=1:w-abs(ShiftScale(1))
                    ImgOut(i,j,:) = ImgInput(i+ShiftScale(2),j+abs(ShiftScale(1)),:);
                end
            end
        
        elseif  ShiftScale(1) <= 0 && ShiftScale(2) <= 0            %向左下平移
            for i=1:h-abs(ShiftScale(2))
                for j=1:w-abs(ShiftScale(1))
                    ImgOut(i+abs(ShiftScale(2)),j,:) = ImgInput(i,j+abs(ShiftScale(1)),:);
                end
            end
        end        
        end


        function ImgOut = ImgReverse(~, ImgInput, dir)
        % Input:    ImgInput    输入图像
        %           dir         颠倒方向
        %                       字符串"vertical"表示垂直方向
        %                       字符串"level"表示水平方向
        % Output:   ImgOut      经过颠倒后的图像数据
        % Usage Sample:         ImgNew = ImgReverse(MyImg, 'vertical')
        %                       表示将MyImg图像垂直翻转

        [h,w,dims] = size(ImgInput);
        ImgOut = zeros(h,w,dims,'uint8');

        switch  dir
            case 'level'
                    ImgOut = ImgInput;
                    for i=1:dims
                        temp = ImgOut(:,:,i)';
                        temp = flip(temp);
                        ImgOut(:,:,i) = temp';
                    end
            case 'vertical'
                    ImgOut = flip(ImgInput);
        end
        end


        function ImgOut = ImgShear(~,ImgInput,dir)
        % Input:    ImgInput    输入图像
        %           dir         切变方向
        %                       第一个元素决定水平方向上的切变,负号表示向左,正号表示向右
        %                       第二个元素决定垂直方向上的平移,负号表示向下,正号表示向上
        % Output:   ImgOut      经过切变后的图像数据
        % Usage Sample:         ImgNew = ImgShear(MyImg, [0,0.2])
        %                       表示将MyImg图像沿着垂直方向向上切变0.2个单位

        %获取输入图像的尺寸   
        [h,w,dims] = size(ImgInput);
        k1 = dir(1);
        k2 = dir(2);
        hnew = h + floor(abs(abs(k2))*w);
        wnew = w + floor(abs(abs(k1))*h);
        ImgOut = zeros(hnew, wnew,dims,'uint8');

        %切变公式：
        %向右上切变: x=x1+k1(h-y1);y=y1+k2(w-x1)
        %向左下切变: x=x1+k1y1;y=y1+k2x1
        %向右下切变: x=x1+k1(h-y1);y=y1+k2x1
        %向左上切变: x=x1+k1y1;y=y1+k2(w-x1)
        %其中h为原图像高度,w为原图像宽度,x,y分别为新图横纵坐标,x1,y1分别为原图横纵坐标,k1,k2分别为水平和垂直方向上的切变系数
        for i=1:hnew
            for j=1:wnew
                %向右上切变，由新图像到原图像的映射
                %x1=(x+k1y-k1h-k1k2w)/(1-k1k2);y1=(y+k2(x-w)-k1k2h)/(1-k1k2)
                if  k1 >= 0 && k2 >= 0              
                    x = round((j+k1*(i-h)-k1*k2*w) / (1-k1*k2));
                    y = round((i+k2*(j-w)-k1*k2*h) / (1-k1*k2));
                    if  x > 0 && x <= w && y > 0 && y <= h
                        ImgOut(i,j,:) = ImgInput(y,x,:);    %最邻近插值
                    end
                elseif  k1 <= 0 && k2 <= 0          %向左上切变  公式为 x1=(x-k1y)/(1-k1k2);y1=(y-k2x)/(1-k1k2),
                    x = round((j-abs(k1)*i) / (1-abs(k1*k2)));
                    y = round((i-abs(k2)*j) / (1-abs(k1*k2)));
                    if  x > 0 && x <= w && y > 0 && y <= h
                        ImgOut(i,j,:) = ImgInput(y,x,:);    %最邻近插值
                    end
                elseif  k1 >= 0 && k2 <= 0          %向右下切变  公式为 x1=(x+k1y-k1h)/(1+k1k2);y1=(y-k2x+k1k2h)/(1+k1k2)
                    x = round((j+k1*(i-h)) / (1+abs(k1*k2)));
                    y = round((i+abs(k2)*(k1*h-j))/(1+abs(k1*k2)));
                    if  x > 0 && x <= w && y > 0 && y <= h
                        ImgOut(i,j,:) = ImgInput(y,x,:);    %最邻近插值
                    end
                elseif  k1 <= 0 && k2 >= 0          %向左下切变  公式为 x1=(x-k1y+k1k2w)/(1+k1k2);y=y1+k(w-x)
                    x = round((j-abs(k1)*i+abs(k1*k2)*w) / (1+abs(k1*k2)));
                    y = round((i+k2*j-abs(k2)*w)/(1+abs(k1*k2)));
                    if  x > 0 && x <= w && y > 0 && y <= h
                        ImgOut(i,j,:) = ImgInput(y,x,:);    %最邻近插值
                    end
                end
            end
        end
        end


        function ImgOut = ImgRotate(~, ImgInput, deg)
        % Input:    ImgInput    输入图像
        %           deg         旋转度数,大于0表示顺时针,小于零表示逆时针         
        % Output:   ImgOut      经过旋转后的图像数据
        % Usage Sample:         ImgNew = ImgRotate(MyImg, -45)
        
        %获取输入图像的尺寸
        [h,w,dims] = size(ImgInput);
        cosdeg = cosd(deg); sindeg = sind(deg);
        %根据旋转中心及旋转角度计算旋转后的图像大小
        hnew = round(h * abs(cosdeg) + w * abs(sindeg));
        wnew = round(h * abs(sindeg) + w * abs(cosdeg));
        
        ImgOut = zeros(hnew,wnew,dims,'uint8');
        
        %旋转矩阵
        rot = [cosdeg, -sindeg 
               sindeg, cosdeg];
        
        %旋转原理说明：
        %由旋转公式可得：
        %           x = x0*cosθ - y0*sinθ
        %           y = x0*sinθ + y0*cosθ
        %           rot = [cosθ -sinθ
        %                  sinθ cosθ]即为旋转因子
        %           [x;y] = rot * [x0;y0] => [x0;y0] = inv(rot) * [x;y] 或 rot \ [x;y]
        %而它是在原点为(0,0)下的旋转因子,在对图像作旋转时,选取图像中心点为旋转中心较为方便
        %此时以(w/2,h/2)为原点,所以此时有 x0 = xr - w/2, y0 = yr - h/2
        %同理,其得到的x,y也是以变换后的图像中心为原点的坐标,而旋转后的图像中心为(wnew/2,hnew/2),
        %所以有 x = xnr - wnew/2, y = ynr - hnew/2, 所以将图像都以中心为原点后可以统一旋转点，
        %得到(x,y)与(x0,y0)的关系,再根据坐标转换把他们转换到以(0,0)(或(1,1))为原点的原始图像中去,
        %就能得到两幅旋转前后的原始图像之间的映射关系,再利用反映射方法,在ImgInput里面寻找由
        %ImgOut反映射回去的点是否存在,若存在,即得到一组映射点,即找对点位,同理将所有点的对应位置找完整,即可得到旋转后的图像
        for i = 1:wnew
            for j = 1:hnew
                %根据新坐标系下的点位推算原坐标点位
                temp = rot \ [i-wnew/2; j-hnew/2];
                x = round(temp(1,1))+round(w/2);
                y = round(temp(2,1))+round(h/2);
                if y > 0 && y <= h && x > 0  && x <= w
                    ImgOut(j, i, :) = ImgInput(y, x, :); %按照最近邻插值法获取图像数据
                end
            end
        end
        end


        function ImgOut = ImgZoom(~,ImgInput,scale)
        % Brief:    图像缩放及双线性差值
        % Input:    filename        输入图像名称
        %           scale           缩放比例
        % Output:   output          经过缩放及插值的图像   
        % Usage Sample:  
        %
        
        %获取图像的尺寸
        [m,n,dims] = size(ImgInput);
        %创建输出矩阵
        if  scale <= 0
            disp('缩放倍数要大于0！');
        else
            m_new = round(m * scale);
            n_new = round(n * scale);
            output = zeros(m_new,n_new,dims);
        end
        
        %扩展原图像矩阵
        imgx = zeros(m+2,n+2,dims);
        %填充中心
        imgx(2:m+1,2:n+1,:) = ImgInput;
        %填充上下边界两行
        imgx(1,2:n+1,:)=ImgInput(1,:,:);imgx(m+2,2:n+1,:)=ImgInput(m,:,:);
        %填充左右边界两列
        imgx(2:m+1,1,:)=ImgInput(:,1,:);imgx(2:m+1,n+2,:)=ImgInput(:,n,:);
        %填充左上角单元格、右上角单元格
        imgx(1,1,:) = ImgInput(1,1,:);imgx(1,n+2,:) = ImgInput(1,n,:);
        %填充左下角单元格、右下角单元格
        imgx(m+2,1,:) = ImgInput(m,1,:);imgx(m+2,n+2,:) = ImgInput(m,n,:);
        
        for oj = 1:n_new         % 对图像进行按列逐元素扫描
            for oi = 1:m_new
                ii = (oi-1)/scale; jj = (oj-1)/scale;
                i = floor(ii); j = floor(jj); % 向下取整
                u = ii - i; v = jj - j;
                i = i + 1; j = j + 1;
                %公式：f(oi,oj) =
                %(1-u)*(1-v)*f(i,j)+(1-u)*v*f(i,j)+u*(1-v)*f(i+1,j,:)+u*v*f(i+1,j+1,:)
                output(oi,oj,:) = (1-u)*(1-v)*imgx(i,j,:) +(1-u)*v*imgx(i,j+1,:)...
                            + u*(1-v)*imgx(i+1,j,:) + u*v*imgx(i+1,j+1,:);
        
            end
        end
        
        ImgOut = uint8(output);
        end


        function Params = ImgTransParamsParse(~,ParamSting,mode)
        % Brief:    图像变换参数解析
        % Input:    ParamSting  图像变换参数源数据(字符串)
        %           mode        图像变换类型
        % Output:   Params      解析后的参数
        % Usage Sample:         Params = ImgTransParamsParse('100,200','shift')
        %                       解析图像平移参数,向右平移100，向上平移200
        
        switch mode
            case    1
                temp = split(ParamSting,',');
                if  length(temp) == 2 && ~isempty(temp{1}) && ~isempty(temp{2}) 
                    Params = [str2double(temp{1}),str2double(temp{2})];
                else
                    Params = [0,0];
                end

            case    2
                Params = str2double(ParamSting);
        end      
        end


        function ImgOut = ImgGraying(~,img)
        % Brief:    图像灰度化
        % Input:    img         输入图像
        % Output:   ImgOut      输出图像
        % Usage Sample:         

        dims = ndims(img);
        if  dims == 3
            ImgOut = 0.2989 * double(img(:,:,1)) + 0.5870 * double(img(:,:,2)) + 0.1140 * double(img(:,:,3));
        else
            ImgOut = img;
        end

        ImgOut = uint8(ImgOut);
        end


        function ImgOut = ImgEqualing(~,img)
        % Brief:    图像均衡化
        % Input:    img         输入图像
        % Output:   ImgOut      输出图像
        % Usage Sample:   

        [M,N,dims]=size(img);

        grey=zeros(1,256);
        prob_accum=zeros(1,256);
        eq_img = zeros(M,N,dims);
        %统计各个灰度级别像素个数
        for m=1:dims
            for i=1:M
                for j=1:N
                    grey(img(i,j,m)+1)=grey(img(i,j,m)+1)+1;
                end
            end
            grey_p=grey/(M*N);%灰度分布概率
            %灰度累计的分布概率
            for a=2:256
                prob_accum(a)=prob_accum(a-1)+grey_p(a);
            end
            %将原来 f 的灰度值映射到新的灰度值 
            for i = 1:M
                for j = 1:N
                    %灰度分布概率乘以255得到具体的灰度值
                    eq_img(i,j,m)=floor(prob_accum(img(i,j,m)+1)*255); 
                end
            end
        end
        
        ImgOut=uint8(eq_img);
        end


        function [scaledface] = scaling(~,faceMat,lowvec,upvec )
        %特征数据规范化
        %输入——faceMat需要进行规范化的图像数据，
        %       lowvec原来的最小值
        %       upvec原来的最大值
        upnew=1;
        lownew=-1;
        [m,n]=size(faceMat);
        scaledface=zeros(m,n);
        for i=1:m
            scaledface(i,:)=lownew+(faceMat(i,:)-lowvec)./(upvec-lowvec)*(upnew-lownew);
        end
        end
        
       function [traindata, trainlabel, testdata, testlabel] = ReadFaceData(~,ratio)
        % Brief:    读取人脸数据，可读取训练集和测试集
        % Input:    ratio       训练集比例
        % Output:   data        数据
        %           label       数据标签
        % Usage Sample: 
        
        rationum = round(ratio * 10);           %根据训练集比例ratio确定一个人的10个样本中，前rationum个样本作为训练集样本
        trainnum = 87*rationum;                 %训练集样本个数
        testnum = 870 - trainnum;               %测试集样本个数
        trainlabel = zeros(trainnum,1);         %创建训练集和测试集的数据及标签存储区域
        traindata = zeros(trainnum,128*128);
        testlabel = zeros(testnum,1);
        testdata = zeros(testnum,128*128);
 
        for i=1:87
            facedatapath = 'ProcessedData\orl';
            facedatapath=strcat(facedatapath,num2str(i),'_');
            pathcache = facedatapath;
            for j=1:10
                facedatapath = pathcache;
                if  j <= rationum
                    facedatapath=strcat(facedatapath,num2str(j),'.bmp');
                    imgdata = imread(facedatapath);
                    traindata((i-1)*rationum+j,:) = imgdata(:)';
                    trainlabel((i-1)*rationum+j)=i;   
                else
                    facedatapath=strcat(facedatapath,num2str(j),'.bmp');
                    imgdata = imread(facedatapath);
                    testdata((i-1)*(10-rationum)+j,:) = imgdata(:)';
                    testlabel((i-1)*(10-rationum)+j)=i;   
                end 
            end
        end
       end


        function datalabel = GetImgDataLabel(~,filename)
        % Brief:    获取图像数据集的标签
        % Input:    Img         输入图像
        % Output:   datalabel   数据标签
        % Usage Sample: 
        
        if  iscell(filename) == 1
            datalabel = zeros(length(filename),1); 
            for i=1:length(filename)
                temp1 = split(filename{i},'_');
                temp2 = split(temp1{1},'l');
                datalabel(i) = str2double(temp2{2});
            end
        else
            temp1 = split(filename,'_');
            temp2 = split(temp1{1},'l');
            datalabel = str2double(temp2{2});
        end
        
        end


        function SetAxesFontFormat(~,axes,TitleString)
        % Brief:    设置axes字体格式
        % Input:    axes        坐标区域
        %           TitleString 坐标标题字符串
        % Output:   None
        % Usage Sample: 
        
            axes.Title.String = TitleString;
            axes.Title.FontName = "楷体";
            axes.Title.FontSize = 12;
            axes.Title.Color = [1 1 1];
            axes.Title.FontWeight = "Bold";
        end

  function IFaces = face_judge(~,I)
    faceDetector = vision.CascadeObjectDetector; % 构造检测器
    bboxes = step(faceDetector, I); % 开始检测，返回结果到bboxes，[x,y,width,heigth]
    if isempty(bboxes)
        IFaces = I;
    else
        boxe = bboxes(:,3).*bboxes(:,4);
        [~,indexboxes] = max(boxe);
        if (bboxes(indexboxes,3)<200)||(bboxes(indexboxes,4)<200)
            IFaces = I;
        else
            IFaces = imcrop(I,bboxes(indexboxes,:));
        end
    end
%     figure, imshow(IFaces), title('Detected faces'); 
  end


        end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %图像处理模块
            app.ImgInfo.Text = '请 输 入 图 像 ~';                                 %图像信息栏初始化
            app.ImgParams.Text = '请 输 入 图 像 ~';                               %图像参数栏初始化
            axes1 = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
            imshow(app.background,'Parent',axes1,'InitialMagnification','fit');
            %人脸识别模块
            app.RecongResultLabel.Text = '请 输 入 图 像 ~';                         %图像信息栏初始化
            axes2 = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
            imshow(app.background,'Parent',axes2,'InitialMagnification','fit');
        end

        % Button pushed function: InputImg
        function InputImgButtonPushed(app, event)
             [app.file, path] = uigetfile({'*.jpg';'*.png';'*.jpeg';'*.bmp';'*.*'},'Choose your file','MultiSelect','on');                       %读取文件后执行下列程序  
             figure(app.UIFigure);
             if  iscell(app.file) == 1                                      %判断是否输入多个图像
                 app.img = {};                                              %创建元胞数组
                 for i=1:length(app.file)
                     app.img{i} = imread(strcat(path, app.file{i}));
                 end
                 imginfo = imfinfo(strcat(path, app.file{1}));
                 axes1 = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);  %创建作图区域
                 cla(axes1);
                 imshow(app.img{1},'Parent',axes1,'InitialMagnification','fit');
                 axes2 = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel_2);  %创建作图区域
                 cla(axes2);
                 imshow(app.img{1},'Parent',axes2,'InitialMagnification','fit');
                 app.ImgInfo.Text = app.CreateImgInfoString(app.file{1},imginfo);
                 app.RecongResultLabel.Text = {'数据导入完成！','等待用户开始识别...','识别结果将显示在这里'};
                 app.isinputimgflag = 1;
                 app.imgtemp = app.img{1};
             elseif app.file ~= 0                                           %避免点击按钮但是未输入图像的情况                                         
                 app.img = imread(strcat(path, app.file));
                 imginfo = imfinfo(strcat(path, app.file));                     
                 axes1 = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                 cla(axes1);
                 imshow(app.img,'Parent',axes1,'InitialMagnification','fit');            %显示图像
                 axes2 = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);  
                 cla(axes2);
                 imshow(app.img,'Parent',axes2,'InitialMagnification','fit');            %显示图像
                 app.ImgInfo.Text = app.CreateImgInfoString(app.file,imginfo);
                 app.RecongResultLabel.Text = {'数据导入完成！','等待用户开始识别...','识别结果将显示在这里'};
                 app.ImgParams.Text={'数据导入完成！','等待获取图像参数...'}
                 app.isinputimgflag = 1;
                 app.imgtemp = app.img;
             end
        end

        % Button pushed function: ClearImgButton
        function ClearImgButtonPushed(app, event)
            if  app.isinputimgflag == 1
                app.img = 0;
                app.imgtemp = 0;
                axes1 = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);  
                cla(axes1);
                     %显示图像
                axes2 = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);  
                cla(axes2);
                imshow(app.background,'Parent',axes2,'InitialMagnification','fit');            %显示图像
                app.ImgInfo.Text = '请 输 入 图 像 ~';
                app.RecongResultLabel.Text = app.ImgInfo.Text;
                app.ImgParams.Text = '请 输 入 图 像 ~';
                app.RecongResultLabel.Text = app.ImgParams.Text;
                app.facedatareadyflag = 0;
                app.isinputimgflag = 0;
            end
        end

        % Button pushed function: GetImgParamButton
        function GetImgParamButtonPushed(app, event)
            if  app.isinputimgflag == 1
                if  iscell(app.img) == 1
                    [MaxVal, MinVal, Avr, Var] = GetImgParams(app,app.img{1});
                elseif  app.isinputimgflag == 1
                    [MaxVal, MinVal, Avr, Var] = GetImgParams(app,app.img);
                end
                ImgParamStrings = {strcat('最大灰度值:',32,32,num2str(MaxVal)),strcat('最小灰度值:',32,32,num2str(MinVal))...
                                   strcat('灰度均值:',32,32,32,32,num2str(Avr)),strcat('灰度值方差:',32,32,num2str(Var))};
                app.ImgParams.Text = ImgParamStrings;
            end
        end

        % Callback function
        function PlotGrayHistButtonPushed(app, event)
            if  iscell(app.img) == 1 && app.isinputimgflag == 1
                figure;app.plotgrayhist(app.img{1});
                title(strcat(app.file,'灰度直方图'));xlabel('灰度值(0~255)');ylabel('个数');
            elseif  app.isinputimgflag == 1
                figure;app.plotgrayhist(app.img);
                title(strcat(app.file,'灰度直方图'));xlabel('灰度值(0~255)');ylabel('个数');
            end
        end

        % Button pushed function: ImgShiftButton
        function ImgShiftButtonPushed(app, event)
            if  app.isinputimgflag == 1
                %判断输入参数是否为空
                if  isempty(app.ImgShiftParams.Value) == 1      
                    params = [0,0];
                else
                    params = app.ImgTransParamsParse(app.ImgShiftParams.Value,1);
                end

                %平移图像并作图
                if  iscell(app.img) == 1
                    %划分区域显示图像
                    app.img{1} = app.imgtemp;
                    app.img{1} = app.ImgShift(app.img{1},params);
                    switch  app.ImgDispModDropDown.Value
                        case    "自动"
                            axes1 = subplot(1,2,1,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.imgtemp,'Parent',axes1,'InitialMagnification','fit');            %显示平移前的图像
                            app.SetAxesFontFormat(axes1,'原图');
                            axes2 = subplot(1,2,2,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.img{1},'Parent',axes2,'InitialMagnification','fit');             %显示平移后的图像
                            app.SetAxesFontFormat(axes2,'平移后的图像');

                        case    "原图"
                            axes1 = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.imgtemp,'Parent',axes1,'InitialMagnification','fit');            %显示平移前的图像
                            app.SetAxesFontFormat(axes1,'原图');

                        otherwise
                            axes1 = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.img{1},'Parent',axes1,'InitialMagnification','fit');            %显示平移前的图像
                            app.SetAxesFontFormat(axes1,'平移后的图像');     
 
                    end

                    %是否调用外部窗口
                    if  app.IsChooseFigureDropDown == "是"
                        switch  app.ImgDispModDropDown.Value
                            case    "自动"
                                figure;
                                subplot(1,2,1);imshow(app.imgtemp);title('原图');
                                subplot(1,2,2);imshow(app.img{1});title('平移后的图像');
    
                            case    "原图"
                                figure;
                                imshow(app.imgtemp);title('原图');

                            otherwise
                                figure;
                                imshow(app.img{1});title('平移后的图像');
                        end
                    end


                else
                    app.img = app.imgtemp;
                    app.img = app.ImgShift(app.img,params);
                    %根据用户需求采用不同的方式显示图像
                    switch  app.ImgDispModDropDown.Value
                        case    "自动"
                            axes1 = subplot(1,2,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.imgtemp,'Parent',axes1,'InitialMagnification','fit');            %显示平移前的图像
                            app.SetAxesFontFormat(axes1,'原图');
                            axes2 = subplot(1,2,2,'Parent',app.ImageDisplayAreaPanel);                           
                            imshow(app.img,'Parent',axes2,'InitialMagnification','fit');                %显示平移后的图像
                            app.SetAxesFontFormat(axes2,'平移后的图像');

                        case    "原图"
                            axes1 = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.imgtemp,'Parent',axes1,'InitialMagnification','fit');            %显示平移前的图像
                            app.SetAxesFontFormat(axes1,'原图');

                        otherwise
                            axes1 = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.img,'Parent',axes1,'InitialMagnification','fit');            %显示平移后的图像
                            app.SetAxesFontFormat(axes1,'平移后的图像'); 

                    end

                    %是否选择外部窗口
                    if  app.IsChooseFigureDropDown.Value == "是"
                        switch  app.ImgDispModDropDown.Value
                            case    "自动"
                                figure;
                                subplot(1,2,1);imshow(app.imgtemp);title('原图');
                                subplot(1,2,2);imshow(app.img);title('平移后的图像');
    
                            case    "原图"
                                figure;
                                imshow(app.imgtemp);title('原图');

                            otherwise
                                figure;
                                imshow(app.img);title('平移后的图像');
                        end
                    end
                end
            end
        end

        % Button pushed function: ImgZoomButton
        function ImgZoomButtonPushed(app, event)
             if  app.isinputimgflag == 1
                %判断输入参数是否为空
                if  isempty(app.ImgZoomParams.Value) == 1      
                    params = 1;
                else
                    params = app.ImgTransParamsParse(app.ImgZoomParams.Value,2);
                end
            
                %缩放图像并作图,多个输入
                if  iscell(app.img) == 1
                    %划分区域显示图像
                    app.img{1} = app.imgtemp;
                    app.img{1} = app.ImgZoom(app.img{1},params);
                    switch  app.ImgDispModDropDown.Value
                        case    "自动"
                            axes1 = subplot(1,2,1,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.imgtemp,'Parent',axes1,'InitialMagnification','fit');            %显示缩放前的图像
                            [h,w,~] = size(app.imgtemp);
                            app.SetAxesFontFormat(axes1,strcat('图像高度:',num2str(h),32,'图像宽度:',num2str(w)));
                            axes2 = subplot(1,2,2,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.img{1},'Parent',axes2,'InitialMagnification','fit');             %显示缩放后的图像
                            [h,w,~] = size(app.img{1});
                            app.SetAxesFontFormat(axes2,strcat('图像高度:',num2str(h),32,'图像宽度:',num2str(w)));
                                                      
                        case    "原图"
                            axes1 = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.imgtemp,'Parent',axes1,'InitialMagnification','fit');            %显示缩放前的图像
                            [h,w,~] = size(app.imgtemp);
                            app.SetAxesFontFormat(axes1,strcat('图像高度:',num2str(h),32,'图像宽度:',num2str(w)));
                        
                        otherwise
                            axes1 = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.img{1},'Parent',axes1,'InitialMagnification','fit');            %显示缩放后的图像
                            [h,w,~] = size(app.img{1});
                            app.SetAxesFontFormat(axes1,strcat('图像高度:',num2str(h),32,'图像宽度:',num2str(w)));
%                             title(strcat('图像高度:',num2str(h),32,'图像宽度:',num2str(w)),"Parent",axes1,"FontName","楷体","FontSize",16,"Color",[1,1,1],"FontWeight","bold");   

                    end

                    %是否选择外部窗口
                    if  app.IsChooseFigureDropDown.Value == "是"
                        switch  app.ImgDispModDropDown.Value
                            case    "自动"
                                figure;
                                subplot(1,2,1);imshow(app.imgtemp);
                                [h,w,~] = size(app.imgtemp);
                                title(strcat('图像高度:',num2str(h),32,'图像宽度:',num2str(w)));
                                subplot(1,2,2);imshow(app.img{1});
                                [h,w,~] = size(app.img{1});
                                title(strcat('图像高度:',num2str(h),32,'图像宽度:',num2str(w))); 
    
                            case    "原图"
                                figure;
                                imshow(app.imgtemp);title('原图');
                                [h,w,~] = size(app.imgtemp);
                                title(strcat('图像高度:',num2str(h),32,'图像宽度:',num2str(w)));

                            otherwise
                                figure;
                                imshow(app.img{1});
                                [h,w,~] = size(app.img{1});
                                title(strcat('图像高度:',num2str(h),32,'图像宽度:',num2str(w))); 
                        end
                    end


                %单个输入
                else
                    app.img = app.imgtemp;
                    app.img = app.ImgZoom(app.img,params);
                    switch  app.ImgDispModDropDown.Value
                        case    "自动"
                            axes1 = subplot(1,2,1,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.imgtemp,'Parent',axes1,'InitialMagnification','fit');            %显示缩放前的图像
                            [h,w,~] = size(app.imgtemp);
                            app.SetAxesFontFormat(axes1,strcat('图像高度:',num2str(h),32,'图像宽度:',num2str(w)));
                            axes2 = subplot(1,2,2,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.img,'Parent',axes2,'InitialMagnification','fit');                %显示缩放后的图像
                            [h,w,~] = size(app.img);
                            app.SetAxesFontFormat(axes2,strcat('图像高度:',num2str(h),32,'图像宽度:',num2str(w)));

                      case    "原图"
                            axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.imgtemp,'Parent',axes,'InitialMagnification','fit');            %显示缩放前的图像
                            [h,w,~] = size(app.imgtemp);
                            app.SetAxesFontFormat(axes,strcat('图像高度:',num2str(h),32,'图像宽度:',num2str(w)));
                        
                      otherwise
                            axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.img,'Parent',axes,'InitialMagnification','fit');                %显示缩放后的图像
                            [h,w,~] = size(app.img);
                            app.SetAxesFontFormat(axes,strcat('图像高度:',num2str(h),32,'图像宽度:',num2str(w)));
                    end

                    %是否选择外部窗口
                    if  app.IsChooseFigureDropDown.Value == "是"
                        switch  app.ImgDispModDropDown.Value
                            case    "自动"
                                figure;
                                subplot(1,2,1);imshow(app.imgtemp);
                                [h,w,~] = size(app.imgtemp);
                                title(strcat('图像高度:',num2str(h),32,'图像宽度:',num2str(w)));
                                subplot(1,2,2);imshow(app.img);
                                [h,w,~] = size(app.img);
                                title(strcat('图像高度:',num2str(h),32,'图像宽度:',num2str(w))); 
    
                            case    "原图"
                                figure;
                                imshow(app.imgtemp);title('原图');
                                [h,w,~] = size(app.imgtemp);
                                title(strcat('图像高度:',num2str(h),32,'图像宽度:',num2str(w)));

                            otherwise
                                figure;
                                imshow(app.img);
                                [h,w,~] = size(app.img);
                                title(strcat('图像高度:',num2str(h),32,'图像宽度:',num2str(w))); 
                        end
                    end
                end
            end
        end

        % Button pushed function: ImgShearButton
        function ImgShearButtonPushed(app, event)
            if  app.isinputimgflag == 1
                %判断输入参数是否为空
                if  isempty(app.ImgShearParams.Value) == 1      
                    params = [0,0];
                else
                    params = app.ImgTransParamsParse(app.ImgShearParams.Value,1);
                end

                %切变图像并作图,多个输入
                if  iscell(app.img) == 1
                    %划分区域显示图像
                    app.img{1} = app.imgtemp;
                    app.img{1} = app.ImgShear(app.img{1},params);
                    switch  app.ImgDispModDropDown.Value
                        case    "自动"
                            axes1 = subplot(1,2,1,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.imgtemp,'Parent',axes1,'InitialMagnification','fit');            %显示切变前的图像
                            app.SetAxesFontFormat(axes1,'原图');
                            axes2 = subplot(1,2,2,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.img{1},'Parent',axes2,'InitialMagnification','fit');             %显示切变后的图像
                            app.SetAxesFontFormat(axes2,'切变后的图像');

                        case    "原图"
                            axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.imgtemp,'Parent',axes,'InitialMagnification','fit');             %显示切变前的图像
                            app.SetAxesFontFormat(axes,'原图');
                        
                        otherwise
                            axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.img{1},'Parent',axes,'InitialMagnification','fit');              %显示切变后的图像
                            app.SetAxesFontFormat(axes,'切变后的图像');            
                    end

                    %是否选择外部窗口
                    if  app.IsChooseFigureDropDown.Value == "是"
                        switch  app.ImgDispModDropDown.Value
                            case    "自动"
                                figure;
                                subplot(1,2,1);imshow(app.imgtemp);
                                title('原图');
                                subplot(1,2,2);imshow(app.img{1});t
                                title('切变后的图像'); 
    
                            case    "原图"
                                figure;
                                imshow(app.imgtemp);title('原图');
                                title('原图');

                            otherwise
                                figure;
                                imshow(app.img{1});
                                title('切变后的图像'); 
                        end
                    end


                %单个输入
                else
                    app.img = app.imgtemp;
                    app.img = app.ImgShear(app.img,params);
                    switch  app.ImgDispModDropDown.Value
                        case    "自动"
                            axes1 = subplot(1,2,1,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.imgtemp,'Parent',axes1,'InitialMagnification','fit');            %显示切变前的图像
                            app.SetAxesFontFormat(axes1,'原图');
                            axes2 = subplot(1,2,2,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.img,'Parent',axes2,'InitialMagnification','fit');                %显示切变后的图像
                            app.SetAxesFontFormat(axes2,'切变后的图像');

                      case    "原图"
                            axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.imgtemp,'Parent',axes,'InitialMagnification','fit');             %显示切变前的图像
                            app.SetAxesFontFormat(axes,'原图');
                        
                      otherwise
                            axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.img,'Parent',axes,'InitialMagnification','fit');                 %显示切变后的图像
                            app.SetAxesFontFormat(axes,'切变后的图像');               
                    end

                    %是否选择外部窗口
                    if  app.IsChooseFigureDropDown.Value == "是"
                        switch  app.ImgDispModDropDown.Value
                            case    "自动"
                                figure;
                                subplot(1,2,1);imshow(app.imgtemp);
                                title('原图');
                                subplot(1,2,2);imshow(app.img);
                                title('切变后的图像'); 
    
                            case    "原图"
                                figure;
                                imshow(app.imgtemp);title('原图');
                                title('原图');

                            otherwise
                                figure;
                                imshow(app.img);
                                title('切变后的图像'); 
                        end
                    end
                end
            end
        end

        % Button pushed function: ImgRotateButton
        function ImgRotateButtonPushed(app, event)
            if  app.isinputimgflag == 1
                %判断输入参数是否为空
                if  isempty(app.ImgRotateParams.Value) == 1      
                    params = 0;
                else
                    params = app.ImgTransParamsParse(app.ImgRotateParams.Value,2);
                end

                %缩放图像并作图,多个输入
                if  iscell(app.img) == 1
                    %划分区域显示图像
                    app.img{1} = app.imgtemp;
                    app.img{1} = app.ImgRotate(app.img{1},params);
                    switch  app.ImgDispModDropDown.Value
                        case    "自动"
                            axes1 = subplot(1,2,1,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.imgtemp,'Parent',axes1,'InitialMagnification','fit');            %显示旋转前的图像
                            app.SetAxesFontFormat(axes1,'原图');
                            axes2 = subplot(1,2,2,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.img{1},'Parent',axes2,'InitialMagnification','fit');             %显示旋转后的图像
                            app.SetAxesFontFormat(axes2,'旋转后的图像');

                        case    "原图"
                            axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.imgtemp,'Parent',axes,'InitialMagnification','fit');             %显示旋转前的图像
                            app.SetAxesFontFormat(axes,'原图');
                        
                        otherwise
                            axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.img{1},'Parent',axes,'InitialMagnification','fit');              %显示旋转后的图像
                            app.SetAxesFontFormat(axes,'旋转后的图像');            
                    end

                    %是否选择外部窗口
                    if  app.IsChooseFigureDropDown.Value == "是"
                        switch  app.ImgDispModDropDown.Value
                            case    "自动"
                                figure;
                                subplot(1,2,1);imshow(app.imgtemp);
                                title('原图');
                                subplot(1,2,2);imshow(app.img{1});
                                title('旋转后的图像'); 
    
                            case    "原图"
                                figure;
                                imshow(app.imgtemp);title('原图');
                                title('原图');

                            otherwise
                                figure;
                                imshow(app.img{1});
                                title('旋转后的图像'); 
                        end
                    end


                %单个输入
                else
                    app.img = app.imgtemp;
                    app.img = app.ImgRotate(app.img,params);
                    switch  app.ImgDispModDropDown.Value
                        case    "自动"
                            axes1 = subplot(1,2,1,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.imgtemp,'Parent',axes1,'InitialMagnification','fit');            %显示旋转前的图像
                            app.SetAxesFontFormat(axes1,'原图');
                            axes2 = subplot(1,2,2,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.img,'Parent',axes2,'InitialMagnification','fit');                %显示旋转后的图像
                            app.SetAxesFontFormat(axes2,'旋转后的图像');

                      case    "原图"
                            axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.imgtemp,'Parent',axes,'InitialMagnification','fit');             %显示旋转前的图像
                            app.SetAxesFontFormat(axes,'原图');
                        
                      otherwise
                            axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.img,'Parent',axes,'InitialMagnification','fit');                 %显示旋转后的图像
                            app.SetAxesFontFormat(axes,'旋转后的图像');               
                    end

                    %是否选择外部窗口
                    if  app.IsChooseFigureDropDown.Value == "是"
                        switch  app.ImgDispModDropDown.Value
                            case    "自动"
                                figure;
                                subplot(1,2,1);imshow(app.imgtemp);
                                title('原图');
                                subplot(1,2,2);imshow(app.img);
                                title('旋转后的图像'); 
    
                            case    "原图"
                                figure;
                                imshow(app.imgtemp);title('原图');
                                title('原图');

                            otherwise
                                figure;
                                imshow(app.img);
                                title('旋转后的图像'); 
                        end
                    end
                end
            end
        end

        % Button pushed function: ImgReverseButton
        function ImgReverseButtonPushed(app, event)
            if  app.isinputimgflag == 1
                if  app.ImgReverseModeButton.SelectedObject.Text == "水平"
                    params = 'level';
                else
                    params = 'vertical';
                end

                %缩放图像并作图,多个输入
                if  iscell(app.img) == 1
                    %划分区域显示图像
                    app.img{1} = app.imgtemp;
                    app.img{1} = app.ImgReverse(app.img{1},params);
                    switch  app.ImgDispModDropDown.Value
                        case    "自动"
                            axes1 = subplot(1,2,1,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.imgtemp,'Parent',axes1,'InitialMagnification','fit');            %显示灰度化图像
                            app.SetAxesFontFormat(axes1,'原图');
                            axes2 = subplot(1,2,2,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.img{1},'Parent',axes2,'InitialMagnification','fit');             %显示颠倒后的图像
                            app.SetAxesFontFormat(axes2,'颠倒后的图像');

                        case    "原图"
                            axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.imgtemp,'Parent',axes,'InitialMagnification','fit');             %显示颠倒前的图像
                            app.SetAxesFontFormat(axes,'原图');
                        
                        otherwise
                            axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.img{1},'Parent',axes,'InitialMagnification','fit');              %显示颠倒后的图像
                            app.SetAxesFontFormat(axes,'颠倒后的图像');            
                    end

                    %是否选择外部窗口
                    if  app.IsChooseFigureDropDown.Value == "是"
                        switch  app.ImgDispModDropDown.Value
                            case    "自动"
                                figure;
                                subplot(1,2,1);imshow(app.imgtemp);
                                title('原图');
                                subplot(1,2,2);imshow(app.img{1});
                                title('颠倒后的图像'); 
    
                            case    "原图"
                                figure;
                                imshow(app.imgtemp);title('原图');
                                title('原图');

                            otherwise
                                figure;
                                imshow(app.img{1});
                                title('颠倒后的图像'); 
                        end
                    end


                %单个输入
                else
                    app.img = app.imgtemp;
                    app.img = app.ImgReverse(app.img,params);
                    switch  app.ImgDispModDropDown.Value
                        case    "自动"
                            axes1 = subplot(1,2,1,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.imgtemp,'Parent',axes1,'InitialMagnification','fit');            %显示颠倒前的图像
                            app.SetAxesFontFormat(axes1,'原图');
                            axes2 = subplot(1,2,2,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.img,'Parent',axes2,'InitialMagnification','fit');                %显示颠倒后的图像
                            app.SetAxesFontFormat(axes2,'颠倒后的图像');
    
                      case    "原图"
                            axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.imgtemp,'Parent',axes,'InitialMagnification','fit');             %显示颠倒前的图像
                            app.SetAxesFontFormat(axes,'原图');
                        
                      otherwise
                            axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.img,'Parent',axes,'InitialMagnification','fit');                 %显示颠倒后的图像
                            app.SetAxesFontFormat(axes,'颠倒后的图像');               
                    end
    
                    %是否选择外部窗口
                    if  app.IsChooseFigureDropDown.Value == "是"
                        switch  app.ImgDispModDropDown.Value
                            case    "自动"
                                figure;
                                subplot(1,2,1);imshow(app.imgtemp);
                                title('原图');
                                subplot(1,2,2);imshow(app.img);
                                title('灰度化图像'); 
    
                            case    "原图"
                                figure;
                                imshow(app.imgtemp);title('原图');
                                title('原图');
    
                            otherwise
                                figure;
                                imshow(app.img);
                                title('灰度化图像'); 
                        end
                    end
                end
            end
        end

        % Button pushed function: ImgTransFuncShowButton
        function ImgTransFuncShowButtonPushed(app, event)
             if  app.isinputimgflag == 1
                figure;
                subplot(2,3,1);imshow(app.imgtemp);title('原图');
                subplot(2,3,2);imshow(app.ImgShift(app.imgtemp,[100,200]));title('平移图像(100,200)');
                subplot(2,3,3);imshow(app.ImgZoom(app.imgtemp,1.5));title('放大1.5倍的图像');
                subplot(2,3,4);imshow(app.ImgShear(app.imgtemp,[0.3,0.4]));title('切变图像(0.3,0.4)');
                subplot(2,3,5);imshow(app.ImgRotate(app.imgtemp,45));title('顺时针旋转45°图像');
                subplot(2,3,6);imshow(app.ImgReverse(app.imgtemp,'vertical'));title('垂直颠倒图像');
            end
        end

        % Button pushed function: PlotGrayHistButton
        function PlotGrayHistButtonPushed2(app, event)
             if  iscell(app.img) == 1 && app.isinputimgflag == 1
                figure;app.plotgrayhist(app.img{1});
                title(strcat(app.file,'灰度直方图'));xlabel('灰度值(0~255)');ylabel('个数');
            elseif  app.isinputimgflag == 1
                figure;app.plotgrayhist(app.img);
                title(strcat(app.file,'灰度直方图'));xlabel('灰度值(0~255)');ylabel('个数');
            end
        end

        % Button pushed function: IngGrayButton
        function IngGrayButtonPushed(app, event)
            if  app.isinputimgflag == 1
                %图像灰度化并作图,多个输入
                if  iscell(app.img) == 1
                    %划分区域显示图像
                    app.img{1} = app.imgtemp;
                    app.img{1} = app.ImgGraying(app.img{1});
                    switch  app.ImgDispModDropDown.Value
                        case    "自动"
                            axes1 = subplot(1,2,1,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.imgtemp,'Parent',axes1,'InitialMagnification','fit');            %显示灰度化前的图像
                            app.SetAxesFontFormat(axes1,'原图');
                            axes2 = subplot(1,2,2,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.img{1},'Parent',axes2,'InitialMagnification','fit');             %显示灰度化后的图像
                            app.SetAxesFontFormat(axes2,'灰度化图像');

                        case    "原图"
                            axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.imgtemp,'Parent',axes,'InitialMagnification','fit');             %显示灰度化前的图像
                            app.SetAxesFontFormat(axes,'原图');
                        
                        otherwise
                            axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.img{1},'Parent',axes,'InitialMagnification','fit');              %显示灰度化后的图像
                            app.SetAxesFontFormat(axes,'灰度化图像');            
                    end

                    %是否选择外部窗口
                    if  app.IsChooseFigureDropDown.Value == "是"
                        switch  app.ImgDispModDropDown.Value
                            case    "自动"
                                figure;
                                subplot(1,2,1);imshow(app.imgtemp);
                                title('原图');
                                subplot(1,2,2);imshow(app.img{1});
                                title('灰度化图像'); 
    
                            case    "原图"
                                figure;
                                imshow(app.imgtemp);title('原图');
                                title('原图');

                            otherwise
                                figure;
                                imshow(app.img{1});
                                title('灰度化的图像'); 
                        end
                    end


                %单个输入
                else
                    app.img = app.imgtemp;
                    app.img = app.ImgGraying(app.img);
                    switch  app.ImgDispModDropDown.Value
                        case    "自动"
                            axes1 = subplot(1,2,1,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.imgtemp,'Parent',axes1,'InitialMagnification','fit');            %显示灰度化前的图像
                            app.SetAxesFontFormat(axes1,'原图');
                            axes2 = subplot(1,2,2,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.img,'Parent',axes2,'InitialMagnification','fit');                %显示灰度化后的图像
                            app.SetAxesFontFormat(axes2,'灰度化图像');
    
                      case    "原图"
                            axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.imgtemp,'Parent',axes,'InitialMagnification','fit');             %显示灰度化前的图像
                            app.SetAxesFontFormat(axes,'原图');
                        
                      otherwise
                            axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.img,'Parent',axes,'InitialMagnification','fit');                 %显示灰度化后的图像
                            app.SetAxesFontFormat(axes,'灰度化图像');               
                    end
    
                    %是否选择外部窗口
                    if  app.IsChooseFigureDropDown.Value == "是"
                        switch  app.ImgDispModDropDown.Value
                            case    "自动"
                                figure;
                                subplot(1,2,1);imshow(app.imgtemp);
                                title('原图');
                                subplot(1,2,2);imshow(app.img);
                                title('灰度化图像'); 
    
                            case    "原图"
                                figure;
                                imshow(app.imgtemp);title('原图');
                                title('原图');
    
                            otherwise
                                figure;
                                imshow(app.img);
                                title('灰度化图像'); 
                        end
                    end
                end
            end
        end

        % Button pushed function: ImgEqualizeButton
        function ImgEqualizeButtonPushed(app, event)
            if  app.isinputimgflag == 1
                %图像均衡化并作图,多个输入
                if  iscell(app.img) == 1
                    %划分区域显示图像
                    app.img{1} = app.imgtemp;
                    app.img{1} = app.ImgEqualing(app.img{1});
                    switch  app.ImgDispModDropDown.Value
                        case    "自动"
                            axes1 = subplot(1,2,1,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.imgtemp,'Parent',axes1,'InitialMagnification','fit');            %显示均衡化前的图像
                            app.SetAxesFontFormat(axes1,'原图');
                            axes2 = subplot(1,2,2,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.img{1},'Parent',axes2,'InitialMagnification','fit');             %显示均衡化后的图像
                            app.SetAxesFontFormat(axes2,'均衡化图像');

                        case    "原图"
                            axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.imgtemp,'Parent',axes,'InitialMagnification','fit');             %显示均衡化前的图像
                            app.SetAxesFontFormat(axes,'原图');
                        
                        otherwise
                            axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.img{1},'Parent',axes,'InitialMagnification','fit');              %显示均衡化后的图像
                            app.SetAxesFontFormat(axes,'均衡化图像');            
                    end

                    %是否选择外部窗口
                    if  app.IsChooseFigureDropDown.Value == "是"
                        switch  app.ImgDispModDropDown.Value
                            case    "自动"
                                figure;
                                subplot(1,2,1);imshow(app.imgtemp);
                                title('原图');
                                subplot(1,2,2);imshow(app.img{1});
                                title('均衡化图像'); 
    
                            case    "原图"
                                figure;
                                imshow(app.imgtemp);title('原图');
                                title('原图');

                            otherwise
                                figure;
                                imshow(app.img{1});
                                title('均衡化的图像'); 
                        end
                    end


                %单个输入
                else
                    app.img = app.imgtemp;
                    app.img = app.ImgEqualing(app.img);
                    switch  app.ImgDispModDropDown.Value
                        case    "自动"
                            axes1 = subplot(1,2,1,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.imgtemp,'Parent',axes1,'InitialMagnification','fit');            %显示均衡化前的图像
                            app.SetAxesFontFormat(axes1,'原图');
                            axes2 = subplot(1,2,2,'Parent',app.ImageDisplayAreaPanel);  
                            imshow(app.img,'Parent',axes2,'InitialMagnification','fit');                %显示均衡化后的图像
                            app.SetAxesFontFormat(axes2,'均衡化图像');
    
                      case    "原图"
                            axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.imgtemp,'Parent',axes,'InitialMagnification','fit');             %显示均衡化前的图像
                            app.SetAxesFontFormat(axes,'原图');
                        
                      otherwise
                            axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                            imshow(app.img,'Parent',axes,'InitialMagnification','fit');                 %显示均衡化后的图像
                            app.SetAxesFontFormat(axes,'均衡化图像');               
                    end
    
                    %是否选择外部窗口
                    if  app.IsChooseFigureDropDown.Value == "是"
                        switch  app.ImgDispModDropDown.Value
                            case    "自动"
                                figure;
                                subplot(1,2,1);imshow(app.imgtemp);
                                title('原图');
                                subplot(1,2,2);imshow(app.img);
                                title('均衡化图像'); 
    
                            case    "原图"
                                figure;
                                imshow(app.imgtemp);title('原图');
                                title('原图');
    
                            otherwise
                                figure;
                                imshow(app.img);
                                title('均衡化图像'); 
                        end
                    end
                end
            end
        end

        % Button pushed function: ReinputImgButton
        function ReinputImgButtonPushed(app, event)
             [app.file, path] = uigetfile({'*.jpg';'*.png';'*.jpeg';'*.bmp';'*.*'},'Choose your file','MultiSelect','on');                       %读取文件后执行下列程序  
            figure(app.UIFigure);
            if  iscell(app.file) == 1                                      %判断是否输入多个图像
                app.img = {};                                              %创建元胞数组
                for i=1:length(app.file)
                    app.img{i} = imread(strcat(path, app.file{i}));
                end
                imginfo = imfinfo(strcat(path, app.file{1}));
                axes1 = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);  %创建作图区域
                imshow(app.img{1},'Parent',axes1,'InitialMagnification','fit');
                axes2 = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);  %创建作图区域
                imshow(app.img{1},'Parent',axes2,'InitialMagnification','fit');
                app.ImgInfo.Text = app.CreateImgInfoString(app.file{1},imginfo);
                app.RecongResultLabel.Text = {'数据导入完成！','等待用户开始识别...','识别结果将显示在这里'};
                app.isinputimgflag = 1;
                app.imgtemp = app.img{1};
            elseif app.file ~= 0                                           %避免点击按钮但是未输入图像的情况                                         
                app.img = imread(strcat(path, app.file));
                imginfo = imfinfo(strcat(path, app.file));                     
                axes1 = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);  
                imshow(app.img,'Parent',axes1,'InitialMagnification','fit');            %显示图像
                axes2 = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);  
                imshow(app.img,'Parent',axes2,'InitialMagnification','fit');            %显示图像
                app.ImgInfo.Text = app.CreateImgInfoString(app.file,imginfo);
                app.RecongResultLabel.Text = {'数据导入完成！','等待用户开始识别...','识别结果将显示在这里'};
                app.isinputimgflag = 1;
                app.imgtemp = app.img;
            end
        end

        % Button pushed function: ModelIintialButton
        function ModelIintialButtonPushed(app, event)
            if  app.isinputimgflag == 1
                axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);  %创建作图区域
                cla(axes);
                imshow(app.imgtemp,'Parent',axes,'InitialMagnification','fit'); 
                title("模型初始化中......",'Parent',axes,"FontName",'楷体',"FontSize",16,"Color",[1,1,1],"FontWeight","bold");
                if  app.svmmodelreadyflag == 1
                    app.svmmodel = app.svmmodeltemp;
                else
                    app.svmmodel = importdata('orl.mat');
                    app.mA = importdata('mA.mat');
                    app.V = importdata('V.mat');
                    app.lowvec = importdata('lowvec.mat');
                    app.upvec = importdata('upvec.mat');
                end
                cla(axes);
                imshow(app.imgtemp,'Parent',axes,'InitialMagnification','fit');
                title("模型初始化完成！",'Parent',axes,"FontName",'楷体',"FontSize",16,"Color",[1,1,1],"FontWeight","bold");    
                app.modelreadyflag = 1;
            end
        end

        % Button pushed function: ModelIintialButton_2
        function ModelIintialButton_2Pushed(app, event)
            if app.isinputimgflag == 1
               axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);  %创建作图区域
               cla(axes);
               imshow(app.imgtemp,'Parent',axes,'InitialMagnification','fit');
               title("数据初始化中......",'Parent',axes,"FontName",'楷体',"FontSize",16,"Color",[1,1,1],"FontWeight","bold");
               if app.video==0
               app.testlabel = app.GetImgDataLabel(app.file);
               else
               app.testlabel = str2double(app.NameNumberText.Value);
               end
               if  iscell(app.img) == 1
                   for  i=1:length(app.img)
                        app.img{i} = app.ImgGraying(app.img{i});
                        %app.img{i} = imresize(app.img{i},[app.orlimgrow,app.orlimgcol]);
                   end
                   
               else
                   app.img = app.imgtemp;
                   app.img = app.ImgGraying(app.img);
                   %app.img = imresize(app.img,[app.orlimgrow,app.orlimgcol]);
               end
               cla(axes);
               imshow(app.imgtemp,'Parent',axes,'InitialMagnification','fit');
               title("数据初始化完成！",'Parent',axes,"FontName",'楷体',"FontSize",16,"Color",[1,1,1],"FontWeight","bold");  
               app.facedatareadyflag = 1;
            end
        end

        % Button pushed function: FaceRecongButton
        function FaceRecongButtonPushed(app, event)
            if  app.modelreadyflag == 1 && app.facedatareadyflag == 1
                axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);  %创建作图区域
                cla(axes);
                imshow(app.imgtemp,'Parent',axes,'InitialMagnification','fit');
                title("人脸识别中......",'Parent',axes,"FontName",'楷体',"FontSize",16,"Color",[1,1,1],"FontWeight","bold");
                if  iscell(app.img) == 1
                    testface = zeros(length(app.img),app.orlimgrow*app.orlimgcol,'double');
                    for i=1:length(app.img)
                        testface(i,:) = double((app.img{i}(:))');
                    end
                else
                    testface = double(app.img(:)');
                end
                
                m=size(testface,1);
                for i=1:m
                    testface(i,:)=testface(i,:)-app.mA;
                end
                
                pcatestface = testface*app.V;
                scaledtestface = app.scaling(pcatestface,app.lowvec,app.upvec);
                [pred,accuracy,~] = svmpredict(app.testlabel, scaledtestface, app.svmmodel);
                             cla(axes);
                imshow(app.imgtemp,'Parent',axes,'InitialMagnification','fit');
                title("人脸识别完成！",'Parent',axes,"FontName",'楷体',"FontSize",16,"Color",[1,1,1],"FontWeight","bold"); 
                if  iscell(app.img) ~= 1
                    if  accuracy(1) == 100
                        resultstring = {strcat('图像名称:',32,32,app.file),strcat('识别结果:',32,32,num2str(pred),'号人物—',app.dic_cell{pred}),strcat('正确结果:',32,32,num2str(app.testlabel),'号人物—',app.dic_cell{app.testlabel}),...
                                        '正确率: 100%  识别正确！'};
                    else
                        resultstring = {strcat('图像名称:',32,32,app.file),strcat('识别结果:',32,32,num2str(pred),'号人物—',app.dic_cell{pred}),strcat('正确结果:',32,32,num2str(app.testlabel),'号人物—',app.dic_cell{app.testlabel}),...
                                        '正确率：0%  识别错误-_-!'};
                    end
                    app.RecongResultLabel.Text = resultstring;
                else
                    resultstring = {strcat('总测试样本个数：',num2str(length(app.img))),strcat('识别正确个数：',num2str(round(accuracy(1)/100*length(app.img)))),strcat('识别正确率：',strcat(num2str(accuracy(1))),'%')};
                    app.RecongResultLabel.Text = resultstring;
                end
            end
        end

        % Button pushed function: ReadTrainingDataButton
        function ReadTrainingDataButtonPushed(app, event)
            if  app.PCAStepFlag == 0
                ratio = str2double(app.TrainSetRatioText.Value);  
                if  isnan(ratio) || isempty(ratio) == 1
                    ratio = 0.8;
                end
                
                if  ratio <= 0 || ratio >= 1
                    ratio = 0.8;
                end
                
                %以下代码为限制小数位数为两位
                str = num2str(ratio);
                if  length(str) > 4
                    ratio = round(ratio*100);
                    ratio = ratio / 100;
                end
                
                %读取训练集及测试集数据和标签
               [app.trainset, app.trainsetlabel, app.testset, app.testsetlabel] = app.ReadFaceData(ratio);
                axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                imshow(app.background,'Parent',axes,'InitialMagnification','fit');
                text(axes,0,650,strcat('数据读取完成，训练集比例为',num2str(ratio),'!'),"FontName",'楷体',"FontSize",20,"Color",[1,1,1],"FontWeight","bold");
                app.PCAStepFlag = 1;
                app.svmmodelreadyflag = 0;
            end
        end

        % Button pushed function: ClearImgButton_2
        function ClearImgButton_2Pushed(app, event)
            if  app.isinputimgflag == 1
                app.img = 0;
                app.imgtemp = 0;
                axes1 = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);  
                cla(axes1);
                     %显示图像
                axes2 = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);  
                cla(axes2);
                imshow(app.background,'Parent',axes2,'InitialMagnification','fit');            %显示图像
                app.ImgInfo.Text = '请 输 入 图 像 ~';
                app.RecongResultLabel.Text = app.ImgInfo.Text;
                app.ImgParams.Text = '请 输 入 图 像 ~';
                app.RecongResultLabel.Text = app.ImgParams.Text;
                app.facedatareadyflag = 0;
                app.isinputimgflag = 0;
            end
        end

        % Button pushed function: SaveModelButton
        function SaveModelButtonPushed(app, event)
              if  app.svmmodelreadyflag == 1
                [filename,filepath] = uiputfile('*.mat');
                figure(app.UIFigure);
                save(strcat(filepath,filename));
            end
        end

        % Value changed function: PCACharactersDimenText
        function PCACharactersDimenTextValueChanged(app, event)
            if  app.PCAStepFlag == 1
                axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                imshow(app.background,'Parent',axes,'InitialMagnification','fit');
                text(axes,0,550,'维度设置完成，请进行下一步操作！',"FontName",'楷体',"FontSize",20,"Color",[1,1,1],"FontWeight","bold");
                text(axes,500,750,'——2.去除平均值！',"FontName",'楷体',"FontSize",20,"Color",[1,1,1],"FontWeight","bold");
                app.PCAStepFlag = 2;
            end
        end

        % Button pushed function: RemoveAverageButton
        function RemoveAverageButtonPushed(app, event)
             app.k = str2double(app.PCACharactersDimenText.Value);
            if  isnan(app.k) || isempty(app.k) == 1
                app.k = 10;
            end
            
            if  app.PCAStepFlag == 1 || app.PCAStepFlag == 2
                app.mA = mean(app.trainset);
                app.meanVec = app.trainset - app.mA;
                axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                imshow(app.background,'Parent',axes,'InitialMagnification','fit');
                text(axes,0,550,'均值去除完成，请进行下一步操作！',"FontName",'楷体',"FontSize",20,"Color",[1,1,1],"FontWeight","bold");
                text(axes,400,750,'——3.求协方差矩阵！',"FontName",'楷体',"FontSize",20,"Color",[1,1,1],"FontWeight","bold");
                app.PCAStepFlag = 3;
            end       
        end

        % Button pushed function: GetCovMatButton
        function GetCovMatButtonPushed(app, event)
            if  app.PCAStepFlag == 3
                app.CovMat = app.meanVec'*app.meanVec/size(app.trainset,1);
                axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                imshow(app.background,'Parent',axes,'InitialMagnification','fit');
                text(axes,480,450,'协方差矩阵求取完成！',"FontName",'楷体',"FontSize",20,"Color",[1,1,1],"FontWeight","bold");
                text(axes,530,650,'请进行下一步操作！',"FontName",'楷体',"FontSize",20,"Color",[1,1,1],"FontWeight","bold");
                text(axes,500,850,'——4.求特征向量！',"FontName",'楷体',"FontSize",20,"Color",[1,1,1],"FontWeight","bold");
                app.PCAStepFlag = 4;
            end
        end

        % Button pushed function: GetEigenvectorButton
        function GetEigenvectorButtonPushed(app, event)
             if  app.PCAStepFlag == 4
                [app.V, ~] = eigs(app.CovMat, app.k);
                axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                imshow(app.background,'Parent',axes,'InitialMagnification','fit');
                text(axes,500,450,'特征向量V求取完成！',"FontName",'楷体',"FontSize",20,"Color",[1,1,1],"FontWeight","bold");
                text(axes,530,650,'请进行下一步操作！',"FontName",'楷体',"FontSize",20,"Color",[1,1,1],"FontWeight","bold");
                text(axes,400,850,'——5.特征向量归一化！',"FontName",'楷体',"FontSize",20,"Color",[1,1,1],"FontWeight","bold");
                app.PCAStepFlag = 5;
            end
        end

        % Button pushed function: EigsNormalizeButton
        function EigsNormalizeButtonPushed(app, event)
            if  app.PCAStepFlag == 5
                for i=1:app.k
                    app.V(:,i)=app.V(:,i)/norm(app.V(:,i));
                end
                axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                imshow(app.background,'Parent',axes,'InitialMagnification','fit');
                text(axes,520,450,'特征向量归一化完成！',"FontName",'楷体',"FontSize",20,"Color",[1,1,1],"FontWeight","bold");
                text(axes,550,650,'请进行下一步操作！',"FontName",'楷体',"FontSize",20,"Color",[1,1,1],"FontWeight","bold");
                text(axes,580,850,'——6.特征投影！',"FontName",'楷体',"FontSize",20,"Color",[1,1,1],"FontWeight","bold");
                app.PCAStepFlag = 6;
            end
        end

        % Button pushed function: EigsProjectButton
        function EigsProjectButtonPushed(app, event)
            if  app.PCAStepFlag == 6
                app.pcaA = app.meanVec * app.V;
                axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                imshow(app.background,'Parent',axes,'InitialMagnification','fit');
                text(axes,570,450,'PCA特征提取完成！',"FontName",'楷体',"FontSize",20,"Color",[1,1,1],"FontWeight","bold");
                text(axes,530,650,'请进行下一步操作！',"FontName",'楷体',"FontSize",20,"Color",[1,1,1],"FontWeight","bold");
                text(axes,520,850,'——显示主成分脸！',"FontName",'楷体',"FontSize",20,"Color",[1,1,1],"FontWeight","bold");
                text(axes,0,1050,'(可直接跳至训练特征数据规范化)',"FontName",'楷体',"FontSize",20,"Color",[1,1,1],"FontWeight","bold");
                app.PCAStepFlag = 7;
            end
        end

        % Button pushed function: DispPCAFaceButton
        function DispPCAFaceButtonPushed(app, event)
            if  app.PCAStepFlag == 7
                imgdata=zeros(128,128);
                for i=1:app.k
                    imgdata(:)=app.V(:,i);
                    if  app.k <= 10
                        axes = subplot(2,5,i,'Parent',app.ImageDisplayAreaPanel);
                        imshow(imgdata,[],'Parent',axes,'InitialMagnification','fit');
                    elseif  app.k > 10 && app.k <= 15
                        axes = subplot(3,5,i,'Parent',app.ImageDisplayAreaPanel);
                        imshow(imgdata,[],'Parent',axes,'InitialMagnification','fit');
                    elseif  app.k > 15 && app.k <= 20
                        axes = subplot(4,5,i,'Parent',app.ImageDisplayAreaPanel);
                        imshow(imgdata,[],'Parent',axes,'InitialMagnification','fit');
                    else
                        if  i <= 20
                            axes = subplot(4,5,i,'Parent',app.ImageDisplayAreaPanel);
                            imshow(imgdata,[],'Parent',axes,'InitialMagnification','fit');
                        end
                    end
                end
                
                if  app.IsChooseFigureDropDown.Value == "是"
                    for i=1:app.k
                        imgdata(:)=app.V(:,i);
                        if  app.k <= 10
                            axes = subplot(2,5,i);
                            imshow(imgdata,[],'Parent',axes,'InitialMagnification','fit');
                        elseif  app.k > 10 && app.k <= 15
                            axes = subplot(3,5,i);
                            imshow(imgdata,[],'Parent',axes,'InitialMagnification','fit');
                        elseif  app.k > 15 && app.k <= 20
                            axes = subplot(4,5,i);
                            imshow(imgdata,[],'Parent',axes,'InitialMagnification','fit');
                        else
                            if  i <= 20
                                axes = subplot(4,5,i);
                                imshow(imgdata,[],'Parent',axes,'InitialMagnification','fit');
                            end
                        end
                    end   
                       
                end
                app.PCAStepFlag = 8;
            end
        end

        % Button pushed function: CharacDataNormalizeButton
        function CharacDataNormalizeButtonPushed(app, event)
             if  app.PCAStepFlag == 7 || app.PCAStepFlag == 8
                app.lowvec=min(app.pcaA);
                app.upvec=max(app.pcaA);
                upnew=1;
                lownew=-1;
                [m,n]=size(app.pcaA);
                app.scaledtrainface=zeros(m,n);
                for i=1:m
                    app.scaledtrainface(i,:)=lownew+(app.pcaA(i,:)-app.lowvec)./(app.upvec-app.lowvec)*(upnew-lownew);
                end
                axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                imshow(app.background,'Parent',axes,'InitialMagnification','fit');
                text(axes,300,450,'训练特征数据规范化完成！',"FontName",'楷体',"FontSize",20,"Color",[1,1,1],"FontWeight","bold");
                text(axes,530,650,'请进行下一步操作！',"FontName",'楷体',"FontSize",20,"Color",[1,1,1],"FontWeight","bold");
                text(axes,520,850,'——SVM样本训练！',"FontName",'楷体',"FontSize",20,"Color",[1,1,1],"FontWeight","bold");
                app.PCAStepFlag = 9;
            end
        end

        % Button pushed function: TrainingSVMButton
        function TrainingSVMButtonPushed(app, event)
             if  app.PCAStepFlag == 9
                TrainParamsC = str2double(app.SVMTrainParamCText.Value);
                TrainParamsG = str2double(app.SVMTrainParamGText.Value);
                if  isnan(TrainParamsC) || isempty(TrainParamsC)
                    TrainParamsC = 100;
                end
                
                if  isnan(TrainParamsG) || isempty(TrainParamsG)
                    TrainParamsG = 2.0;
                end 
                
                trainparams = strcat('-c',32,32,num2str(TrainParamsC),32,'-g',32,num2str(TrainParamsG));
                app.svmmodeltemp = svmtrain(app.trainsetlabel,app.scaledtrainface,trainparams);
                axes = subplot(1,1,1,'Parent',app.ImageDisplayAreaPanel);
                imshow(app.background,'Parent',axes,'InitialMagnification','fit');
                text(axes,430,650,'SVM分类器模型训练完成！',"FontName",'楷体',"FontSize",20,"Color",[1,1,1],"FontWeight","bold");
                text(axes,450,850,'您可以选择导入该模型！',"FontName",'楷体',"FontSize",20,"Color",[1,1,1],"FontWeight","bold");
                app.svmmodelreadyflag = 1;
                app.PCAStepFlag = 0;
            end
        end

        % Button pushed function: videoinputImgButton
        function videoinputImgButtonPushed(app, event)
            app.video=1;
            hard=imaqhwinfo;
name=hard.InstalledAdaptors{1};
vid=videoinput(name,1);
preview(vid);
start(vid);
pause(1);
img01=getsnapshot(vid);
mImageCurrent = uint8(img01);
mFaceResult   = app.face_judge(mImageCurrent);                  %人脸识别截取后的图片，可用于进一步处理、
                    if numel(size(mFaceResult))>2                              %rgb图
                        mmFaceResult  = rgb2gray(mFaceResult);
                    else
                        mmFaceResult = mFaceResult;
                    end
                    finalResult = imresize(mmFaceResult,[128,128]) ;
filename=int2str(0);
filename=[filename,'.bmp'];
imwrite(finalResult,filename);
stop(vid);
closepreview(vid);
delete(vid);
app.namenumber=find(strcmp(app.dic_cell, app.NameText.Value));  %Strcell是cell数组，str1是要查找的字符串，返回位置m
app.NameNumberText.Value=num2str(app.namenumber)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '图标.png');
            app.PCALabel = uilabel(app.UIFigure);
            app.PCALabel.Text = '基于PCA的人脸识别实验平台';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '图像预处理模块';
            app.MainFunctionBlockPanel = uipanel(app.Tab);
            app.MainFunctionBlockPanel.Title = '图像输入控制';
            app.IngGrayButton = uibutton(app.MainFunctionBlockPanel, 'push');
            app.IngGrayButton.ButtonPushedFcn = createCallbackFcn(app, @IngGrayButtonPushed, true);
            app.IngGrayButton.Text = '灰度化';
            app.ImgEqualizeButton = uibutton(app.MainFunctionBlockPanel, 'push');
            app.ImgEqualizeButton.ButtonPushedFcn = createCallbackFcn(app, @ImgEqualizeButtonPushed, true);
            app.ImgEqualizeButton.Text = '均衡化';
            app.Label = uilabel(app.MainFunctionBlockPanel);
            app.Label.Text = '图像显示类型';
            app.ImgDispModDropDown = uidropdown(app.MainFunctionBlockPanel);
            app.ImgDispModDropDown.Items = {'自动', '原图', '新图'};
            app.ImgDispModDropDown.Value = '自动';
            app.Label_2 = uilabel(app.MainFunctionBlockPanel);
            app.Label_2.Text = '是否选择外部图窗';
            app.IsChooseFigureDropDown = uidropdown(app.MainFunctionBlockPanel);
            app.IsChooseFigureDropDown.Items = {'否', '是'};
            app.IsChooseFigureDropDown.Value = '否';
            app.InputImg = uibutton(app.MainFunctionBlockPanel, 'push');
            app.InputImg.ButtonPushedFcn = createCallbackFcn(app, @InputImgButtonPushed, true);
            app.InputImg.Text = '输入图像';
            app.ClearImgButton = uibutton(app.MainFunctionBlockPanel, 'push');
            app.ClearImgButton.ButtonPushedFcn = createCallbackFcn(app, @ClearImgButtonPushed, true);
            app.ClearImgButton.Text = '清除图像';
            app.ImgProcessingToolBoxPanel = uipanel(app.Tab);
            app.ImgProcessingToolBoxPanel.Title = '图像处理控制';
            app.GetImgParamButton = uibutton(app.ImgProcessingToolBoxPanel, 'push');
            app.GetImgParamButton.ButtonPushedFcn = createCallbackFcn(app, @GetImgParamButtonPushed, true);
            app.GetImgParamButton.Text = '获取图像参数';
            app.ImgTransFuncShowButton = uibutton(app.ImgProcessingToolBoxPanel, 'push');
            app.ImgTransFuncShowButton.ButtonPushedFcn = createCallbackFcn(app, @ImgTransFuncShowButtonPushed, true);
            app.ImgTransFuncShowButton.Text = '图像变换功能演示';
            app.ImgShiftButton = uibutton(app.ImgProcessingToolBoxPanel, 'push');
            app.ImgShiftButton.ButtonPushedFcn = createCallbackFcn(app, @ImgShiftButtonPushed, true);
            app.ImgShiftButton.Text = '图像平移';
            app.Label_3 = uilabel(app.ImgProcessingToolBoxPanel);
            app.Label_3.Text = '平移参数';
            app.ImgShiftParams = uieditfield(app.ImgProcessingToolBoxPanel, 'text');
            app.Label_4 = uilabel(app.ImgProcessingToolBoxPanel);
            app.Label_4.Text = '缩放比例';
            app.ImgZoomParams = uieditfield(app.ImgProcessingToolBoxPanel, 'text');
            app.ImgShearButton = uibutton(app.ImgProcessingToolBoxPanel, 'push');
            app.ImgShearButton.ButtonPushedFcn = createCallbackFcn(app, @ImgShearButtonPushed, true);
            app.ImgShearButton.Text = '图像切变';
            app.Label_5 = uilabel(app.ImgProcessingToolBoxPanel);
            app.Label_5.Text = '切变参数';
            app.ImgShearParams = uieditfield(app.ImgProcessingToolBoxPanel, 'text');
            app.Label_6 = uilabel(app.ImgProcessingToolBoxPanel);
            app.Label_6.Text = '旋转度数';
            app.ImgRotateParams = uieditfield(app.ImgProcessingToolBoxPanel, 'text');
            app.ImgReverseButton = uibutton(app.ImgProcessingToolBoxPanel, 'push');
            app.ImgReverseButton.ButtonPushedFcn = createCallbackFcn(app, @ImgReverseButtonPushed, true);
            app.ImgReverseButton.Text = '图像颠倒';
            app.ImgReverseModeButton = uibuttongroup(app.ImgProcessingToolBoxPanel);
            app.vertical = uiradiobutton(app.ImgReverseModeButton);
            app.vertical.Text = '垂直';
            app.vertical.Value = true;
            app.level = uiradiobutton(app.ImgReverseModeButton);
            app.level.Text = '水平';
            app.Label_7 = uilabel(app.ImgProcessingToolBoxPanel);
            app.Label_7.Text = '图像处理功能演示';
            app.ImgZoomButton = uibutton(app.ImgProcessingToolBoxPanel, 'push');
            app.ImgZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ImgZoomButtonPushed, true);
            app.ImgZoomButton.Text = '图像缩放';
            app.ImgRotateButton = uibutton(app.ImgProcessingToolBoxPanel, 'push');
            app.ImgRotateButton.ButtonPushedFcn = createCallbackFcn(app, @ImgRotateButtonPushed, true);
            app.ImgRotateButton.Text = '图像旋转';
            app.PlotGrayHistButton = uibutton(app.ImgProcessingToolBoxPanel, 'push');
            app.PlotGrayHistButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGrayHistButtonPushed2, true);
            app.PlotGrayHistButton.Text = '绘制灰度直方图';
            app.ImgParamsPanel = uipanel(app.Tab);
            app.ImgParamsPanel.Title = '图像参数显示';
            app.ImgParams = uilabel(app.ImgParamsPanel);
            app.ImgParams.Text = '';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '人脸识别模块';
            app.Panel_8 = uipanel(app.Tab_2);
            app.Panel_8.Title = '训练过程控制';
            app.Label_8 = uilabel(app.Panel_8);
            app.Label_8.Text = '训练集比例';
            app.TrainSetRatioText = uitextarea(app.Panel_8);
            app.CharacDataNormalizeButton = uibutton(app.Panel_8, 'push');
            app.CharacDataNormalizeButton.ButtonPushedFcn = createCallbackFcn(app, @CharacDataNormalizeButtonPushed, true);
            app.CharacDataNormalizeButton.Text = '训练特征数据规范化';
            app.cLabel = uilabel(app.Panel_8);
            app.cLabel.Text = '惩罚参数c';
            app.SVMTrainParamCText = uitextarea(app.Panel_8);
            app.gLabel = uilabel(app.Panel_8);
            app.gLabel.Text = '核函数参数g';
            app.SVMTrainParamGText = uitextarea(app.Panel_8);
            app.RemoveAverageButton = uibutton(app.Panel_8, 'push');
            app.RemoveAverageButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAverageButtonPushed, true);
            app.RemoveAverageButton.Text = '去除平均值';
            app.RemoveAverageLabel = uilabel(app.Panel_8);
            app.RemoveAverageLabel.Text = '2.去除平均值';
            app.GetCovMatButton = uibutton(app.Panel_8, 'push');
            app.GetCovMatButton.ButtonPushedFcn = createCallbackFcn(app, @GetCovMatButtonPushed, true);
            app.GetCovMatButton.Text = '求协方差矩阵';
            app.GetCovMat = uilabel(app.Panel_8);
            app.GetCovMat.Text = '3.求协方差矩阵';
            app.GetEigenvectorButton = uibutton(app.Panel_8, 'push');
            app.GetEigenvectorButton.ButtonPushedFcn = createCallbackFcn(app, @GetEigenvectorButtonPushed, true);
            app.GetEigenvectorButton.Text = '求特征向量';
            app.GetEigenvectorLabel = uilabel(app.Panel_8);
            app.GetEigenvectorLabel.Text = '4.求特征向量';
            app.EigsNormalizeButton = uibutton(app.Panel_8, 'push');
            app.EigsNormalizeButton.ButtonPushedFcn = createCallbackFcn(app, @EigsNormalizeButtonPushed, true);
            app.EigsNormalizeButton.Text = '归一化';
            app.EigsNormalizeLabel = uilabel(app.Panel_8);
            app.EigsNormalizeLabel.Text = '5.特征向量归一化';
            app.EigsProjectButton = uibutton(app.Panel_8, 'push');
            app.EigsProjectButton.ButtonPushedFcn = createCallbackFcn(app, @EigsProjectButtonPushed, true);
            app.EigsProjectButton.Text = '特征投影';
            app.EigsProjectLabel = uilabel(app.Panel_8);
            app.EigsProjectLabel.Text = '6.特征投影';
            app.PCALabel_2 = uilabel(app.Panel_8);
            app.PCALabel_2.Text = '1.设置PCA特征维度';
            app.PCACharactersDimenText = uieditfield(app.Panel_8, 'text');
            app.PCACharactersDimenText.ValueChangedFcn = createCallbackFcn(app, @PCACharactersDimenTextValueChanged, true);
            app.PCALabel_3 = uilabel(app.Panel_8);
            app.PCALabel_3.Text = 'PCA流程演示';
            app.DispPCAFaceButton = uibutton(app.Panel_8, 'push');
            app.DispPCAFaceButton.ButtonPushedFcn = createCallbackFcn(app, @DispPCAFaceButtonPushed, true);
            app.DispPCAFaceButton.Text = '显示主成分脸';
            app.TrainingSVMButton = uibutton(app.Panel_8, 'push');
            app.TrainingSVMButton.ButtonPushedFcn = createCallbackFcn(app, @TrainingSVMButtonPushed, true);
            app.TrainingSVMButton.Text = 'SVM样本训练';
            app.SaveModelButton = uibutton(app.Panel_8, 'push');
            app.SaveModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelButtonPushed, true);
            app.SaveModelButton.Text = '保存模型';
            app.ReadTrainingDataButton = uibutton(app.Tab_2, 'push');
            app.ReadTrainingDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadTrainingDataButtonPushed, true);
            app.ReadTrainingDataButton.Text = '读取训练数据';
            app.Panel_9 = uipanel(app.Tab_2);
            app.Panel_9.Title = '识别模块控制';
            app.ModelIintialButton = uibutton(app.Panel_9, 'push');
            app.ModelIintialButton.ButtonPushedFcn = createCallbackFcn(app, @ModelIintialButtonPushed, true);
            app.ModelIintialButton.Text = '模型初始化';
            app.FaceRecongButton = uibutton(app.Panel_9, 'push');
            app.FaceRecongButton.ButtonPushedFcn = createCallbackFcn(app, @FaceRecongButtonPushed, true);
            app.FaceRecongButton.Text = '开始人脸识别';
            app.ModelIintialButton_2 = uibutton(app.Panel_9, 'push');
            app.ModelIintialButton_2.ButtonPushedFcn = createCallbackFcn(app, @ModelIintialButton_2Pushed, true);
            app.ModelIintialButton_2.Text = '图像数据初始化';
            app.ClearImgButton_2 = uibutton(app.Panel_9, 'push');
            app.ClearImgButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearImgButton_2Pushed, true);
            app.ClearImgButton_2.Text = '清除图像';
            app.ReinputImgButton = uibutton(app.Panel_9, 'push');
            app.ReinputImgButton.ButtonPushedFcn = createCallbackFcn(app, @ReinputImgButtonPushed, true);
            app.ReinputImgButton.Text = '重新输入图像';
            app.videoinputImgButton = uibutton(app.Panel_9, 'push');
            app.videoinputImgButton.ButtonPushedFcn = createCallbackFcn(app, @videoinputImgButtonPushed, true);
            app.videoinputImgButton.Text = '从摄像头获取人脸图像';
            app.Label_9 = uilabel(app.Panel_9);
            app.Label_9.Text = '人物姓名';
            app.NameText = uitextarea(app.Panel_9);
            app.Label_10 = uilabel(app.Panel_9);
            app.Label_10.Text = '人物编号';
            app.NameNumberText = uitextarea(app.Panel_9);
            app.ImageDisplayAreaPanel = uipanel(app.UIFigure);
            app.ImageDisplayAreaPanel.Title = '图像显示';
            app.RecongnitionResultPanel = uipanel(app.UIFigure);
            app.RecongnitionResultPanel.Title = '识别结果';
            app.RecongResultLabel = uilabel(app.RecongnitionResultPanel);
            app.RecongResultLabel.Text = '';
            app.ImageInfoColumnPanel = uipanel(app.UIFigure);
            app.ImageInfoColumnPanel.Title = '图像信息';
            app.ImgInfo = uilabel(app.ImageInfoColumnPanel);
            app.ImgInfo.Text = '';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'ui动图背景1.gif');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Face_Recognition_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end